classdef dlpmodel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConverionProfilePrediction      matlab.ui.Figure
        printingsetupLabel              matlab.ui.control.Label
        curekineticsLabel               matlab.ui.control.Label
        Drexel_Image                    matlab.ui.control.Image
        curekinetics_Image              matlab.ui.control.Image
        resinpropertiesLabel            matlab.ui.control.Label
        printparamsTable                matlab.ui.control.Table
        editlayernumberButton           matlab.ui.control.Button
        editlayerthicknessesButton      matlab.ui.control.Button
        editexposuretimesButton         matlab.ui.control.Button
        editgrayscalevaluesButton       matlab.ui.control.Button
        editvoidstructuresButton        matlab.ui.control.Button
        powerdensityLabel               matlab.ui.control.Label
        formulationLabel                matlab.ui.control.Label
        materialLabel                   matlab.ui.control.Label
        materialField                   matlab.ui.control.EditField
        photoinitiatorEditFieldLabel    matlab.ui.control.Label
        photoinitiatorField             matlab.ui.control.EditField
        wtEditFieldLabel_5              matlab.ui.control.Label
        piconcentrationField            matlab.ui.control.NumericEditField
        photoabsorberEditFieldLabel     matlab.ui.control.Label
        photoabsorberField              matlab.ui.control.EditField
        wtEditFieldLabel_6              matlab.ui.control.Label
        paconcentrationField            matlab.ui.control.NumericEditField
        depthofpenetrationDpmumLabel    matlab.ui.control.Label
        depthofpenetrationField         matlab.ui.control.NumericEditField
        criticalenergyEcmJcm2Label      matlab.ui.control.Label
        criticalenergyField             matlab.ui.control.NumericEditField
        criticalconversionalpha_cLabel  matlab.ui.control.Label
        criticalconversionField         matlab.ui.control.NumericEditField
        oxygeninhibitionEoimJcm2Label   matlab.ui.control.Label
        oxygeninhibitionField           matlab.ui.control.NumericEditField
        ultimateconversionalpha_uLabel  matlab.ui.control.Label
        ultimateconversionField         matlab.ui.control.NumericEditField
        kineticrateconstantkEditFieldLabel  matlab.ui.control.Label
        kineticrateconstantField        matlab.ui.control.NumericEditField
        reactionordernLabel             matlab.ui.control.Label
        reactionorderField              matlab.ui.control.NumericEditField
        doseratefactorwEditFieldLabel   matlab.ui.control.Label
        doseratefactorField             matlab.ui.control.NumericEditField
        printerEditFieldLabel           matlab.ui.control.Label
        printerField                    matlab.ui.control.EditField
        ILabel                          matlab.ui.control.Label
        equalsignLabel                  matlab.ui.control.Label
        xgsvLabel                       matlab.ui.control.Label
        unitpowerdensityField           matlab.ui.control.NumericEditField
        darkpowerdensityField           matlab.ui.control.NumericEditField
        plussignLabel                   matlab.ui.control.Label
        predictButton                   matlab.ui.control.Button
        zintervalmumLabel               matlab.ui.control.Label
        zintervalField                  matlab.ui.control.NumericEditField
        timeintervalsLabel              matlab.ui.control.Label
        tintervalField                  matlab.ui.control.NumericEditField
        showstepwiseprintingSwitchLabel  matlab.ui.control.Label
        showprintingSwitch              matlab.ui.control.Switch
        savetoMatlabworkspaceLabel      matlab.ui.control.Label
        savetoworkspaceSwitch           matlab.ui.control.Switch
        Image                           matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ConverionProfilePrediction.Name = 'Conversion Profile Prediction - z direction, 1 pixel';
            
            app.depthofpenetrationDpmumLabel.Text = ['depth of penetration, Dp (' char(956) 'm)'];
            app.criticalconversionalpha_cLabel.Text = ['critical conversion, ' char(945) '_c'];
            app.ultimateconversionalpha_uLabel.Text = ['ultimate conversion, ' char(945) '_u'];
            app.doseratefactorwEditFieldLabel.Text = ['dose rate factor, ' char(969)];
            app.zintervalmumLabel.Text = ['z interval (' char(956) 'm)'];
            
            nl = 16; layerNumbers = (1:nl)';
            layerThicknesses = 100*ones(nl,1);
            exposureTimes = 20*ones(nl,1);
            grayscaleValues = 20*ones(nl,1);
            voidStructures = zeros(nl,1);
            
            app.printparamsTable.Data = table(layerNumbers,layerThicknesses,exposureTimes,grayscaleValues,voidStructures);
            app.printparamsTable.ColumnName = {sprintf('layer\nnumber'),sprintf(['layer\nthickness \n(' char(956) 'm)']),...
                sprintf('exposure\ntime\n(s)'),sprintf('grayscale\nvalue'),sprintf('void\nstructure')};

            s = uistyle('HorizontalAlignment','center');
            addStyle(app.printparamsTable,s,'table','');
        end

        % Button pushed function: predictButton
        function predictButtonPushed(app, event)
            material = app.materialField.Value;
            photoinitiator = app.photoinitiatorField.Value;
            piconc = app.piconcentrationField.Value/100;
            photoabsorber = app.photoabsorberField.Value;
            paconc = app.paconcentrationField.Value/100;
            
            formulation = struct('material',material,...
                            'photoinitiator',photoinitiator,...
                            'piConcentration',piconc,...
                            'photoabsorber',photoabsorber,...
                            'paConcentration',paconc);
            
            printername = app.printerField.Value;
            unitpd = app.unitpowerdensityField.Value;
            darkpd = app.darkpowerdensityField.Value;
            
            printer = struct('name',printername,...
                         'unitPowerDensity',unitpd,...
                         'darkPowerDensity',darkpd);
            
            printParams = app.printparamsTable.Data;
            
            nl = size(printParams,1);
            dz = printParams.layerThicknesses;
            dt = printParams.exposureTimes;
            gsv = printParams.grayscaleValues;
            vstr = logical(printParams.voidStructures);
            
            Dp = app.depthofpenetrationField.Value;
            Ec = app.criticalenergyField.Value;
            ac = app.criticalconversionField.Value;
            Eoi = app.oxygeninhibitionField.Value;
            
            au = app.ultimateconversionField.Value;
            k = app.kineticrateconstantField.Value;
            n = app.reactionorderField.Value;
            w = app.doseratefactorField.Value;
            
            zinterval = app.zintervalField.Value;
%           tinterval = app.tintervalField.Value;
            
            showprinting = app.showprintingSwitch.Value;
            switch showprinting
                case 'On'; stepplot = true;
                case 'Off'; stepplot = false;
            end
            
            conversionprofile = zconversionprofileonepixel_simplified...
                                       (formulation,printer,...
                                       nl,dz,dt,gsv,vstr,...
                                       Dp,Ec,ac,Eoi,au,k,n,w,...
                                       zinterval,stepplot,true);
             
            saveValue = app.savetoworkspaceSwitch.Value;
            switch saveValue
                case 'Yes'; saveprediction = true;
                case 'No'; saveprediction = false;
            end

            if saveprediction
                issaved = false;
                while ~issaved
                    vn = inputdlg('Please type in a name for the prediction:');
                    if ~isequal(length(vn),0)
                        if ~isvarname(vn{1})
                            waitfor(msgbox(sprintf(['Please enter a valid name.\n'...
                                'Matlab variable names must begin with a letter，contain no spaces, ' ...
                                'and use only allowed special characters.']),...
                                '! INVALID VARIABLE NAME !','error'));
                        else
                            assignin('base',vn{1},conversionprofile); issaved = true;
                        end
                    else
                        issaved = true;
                    end
                end                    
            end
        end

        % Button pushed function: editlayernumberButton
        function editlayernumberButtonButtonPushed(app, event)
            input1 = inputdlg('Please enter a new number of layers:');
            if ~isempty(input1)
                if ~isempty(input1{1})
                    nn = str2num(input1{1});
                    nn = nn(1); if nn==0; nn=1; end
                    printParams = app.printparamsTable.Data;
                    n = size(printParams,1);
                    if ~(nn>n)
                        app.printparamsTable.Data  = printParams(1:nn,{'layerNumbers','layerThicknesses','exposureTimes','grayscaleValues','voidStructures'});
                    else
                        app.printparamsTable.Data(n+1:nn,{'layerNumbers','layerThicknesses','exposureTimes','grayscaleValues','voidStructures'})...
                            = [table((n+1:nn)'),printParams(n*ones(nn-n,1),{'layerThicknesses','exposureTimes','grayscaleValues','voidStructures'})];
                    end
                end
            end
        end

        % Button pushed function: editlayerthicknessesButton
        function editlayerthicknessesButtonButtonPushed(app, event)
            input1 = inputdlg(sprintf(['Please enter numbers separated by space or comma.\nIf only one number is entered, '...
                                       'the value will be assigned to all layers.']),'Layer thicknesses', [1 120]);
            if ~isempty(input1)
                if ~isempty(input1{1})
                    dz = (str2num(input1{1}))';
                    nn = length(dz);
                    
                    printParams = app.printparamsTable.Data;
                    n = size(printParams,1);
                    if nn == 1
                        app.printparamsTable.Data(1:n,{'layerNumbers','layerThicknesses','exposureTimes','grayscaleValues','voidStructures'})...
                            = [printParams(1:n,{'layerNumbers'}),table(dz*ones(n,1)),printParams(1:n,{'exposureTimes','grayscaleValues','voidStructures'})];
                    else
                       if nn < n
                        app.printparamsTable.Data(1:nn,{'layerNumbers','layerThicknesses','exposureTimes','grayscaleValues','voidStructures'})...
                            = [printParams(1:nn,{'layerNumbers'}),table(dz),printParams(1:nn,{'exposureTimes','grayscaleValues','voidStructures'})];
                        msgbox(sprintf(['You input ' num2str(nn) ' numbers.\nThey were assigned to the first ' num2str(nn) ' layers.']),'Layer thicknesses');
                       elseif nn == n
                        app.printparamsTable.Data(1:n,{'layerNumbers','layerThicknesses','exposureTimes','grayscaleValues','voidStructures'})...
                            = [printParams(1:n,{'layerNumbers'}),table(dz),printParams(1:n,{'exposureTimes','grayscaleValues','voidStructures'})];
                       elseif nn > n
                        app.printparamsTable.Data(1:n,{'layerNumbers','layerThicknesses','exposureTimes','grayscaleValues','voidStructures'})...
                            = [printParams(1:n,{'layerNumbers'}),table(dz(1:n)),printParams(1:n,{'exposureTimes','grayscaleValues','voidStructures'})];
                        msgbox(sprintf(['You input ' num2str(nn) ' numbers.\nThe extra numbers were discarded.']),'Layer thicknesses');
                       end
                    
                    end
                end
            end
            
        end

        % Button pushed function: editexposuretimesButton
        function editexposuretimesButtonButtonPushed(app, event)
            input1 = inputdlg(sprintf(['Please enter numbers separated by space or comma.\nIf only one number is entered, '...
                                       'the value will be assigned to all layers.']),'Exposure times', [1 120]);
            if ~isempty(input1)
                if ~isempty(input1{1})
                    dt = (str2num(input1{1}))';
                    nn = length(dt);
                    
                    printParams = app.printparamsTable.Data;
                    n = size(printParams,1);
                    if nn == 1
                        app.printparamsTable.Data(1:n,{'layerNumbers','layerThicknesses','exposureTimes','grayscaleValues','voidStructures'})...
                            = [printParams(1:n,{'layerNumbers','layerThicknesses'}),table(dt*ones(n,1)),printParams(1:n,{'grayscaleValues','voidStructures'})];
                    else
                       if nn < n
                        app.printparamsTable.Data(1:nn,{'layerNumbers','layerThicknesses','exposureTimes','grayscaleValues','voidStructures'})...
                            = [printParams(1:nn,{'layerNumbers','layerThicknesses'}),table(dt),printParams(1:nn,{'grayscaleValues','voidStructures'})];
                        msgbox(sprintf(['You input ' num2str(nn) ' numbers.\nThey were assigned to the first ' num2str(nn) ' layers.']),'Exposure Times');
                       elseif nn == n
                        app.printparamsTable.Data(1:n,{'layerNumbers','layerThicknesses','exposureTimes','grayscaleValues','voidStructures'})...
                            = [printParams(1:n,{'layerNumbers','layerThicknesses'}),table(dt),printParams(1:n,{'grayscaleValues','voidStructures'})];
                       elseif nn > n
                        app.printparamsTable.Data(1:n,{'layerNumbers','layerThicknesses','exposureTimes','grayscaleValues','voidStructures'})...
                            = [printParams(1:n,{'layerNumbers','layerThicknesses'}),table(dt(1:n)),printParams(1:n,{'grayscaleValues','voidStructures'})];
                        msgbox(sprintf(['You input ' num2str(nn) ' numbers.\nThe extra numbers were discarded.']),'Exposure times');
                       end
                    
                    end
                end
            end
            
        end

        % Button pushed function: editgrayscalevaluesButton
        function editgrayscalevaluesButtonButtonPushed(app, event)
            input1 = inputdlg(sprintf(['Please enter numbers separated by space or comma.\nIf only one number is entered, '...
                                       'the value will be assigned to all layers.']),'Grayscale values', [1 120]);
            if ~isempty(input1)
                if ~isempty(input1{1})
                    gsv = (str2num(input1{1}))';
                    nn = length(gsv);
                    
                    printParams = app.printparamsTable.Data;
                    n = size(printParams,1);
                    if nn == 1
                        app.printparamsTable.Data(1:n,{'layerNumbers','layerThicknesses','exposureTimes','grayscaleValues','voidStructures'})...
                            = [printParams(1:n,{'layerNumbers','layerThicknesses','exposureTimes'}),table(gsv*ones(n,1)),printParams(1:n,{'voidStructures'})];
                    else
                       if nn < n
                        app.printparamsTable.Data(1:nn,{'layerNumbers','layerThicknesses','exposureTimes','grayscaleValues','voidStructures'})...
                            = [printParams(1:nn,{'layerNumbers','layerThicknesses','exposureTimes'}),table(gsv),printParams(1:nn,{'voidStructures'})];
                        msgbox(sprintf(['You input ' num2str(nn) ' numbers.\nThey were assigned to the first ' num2str(nn) ' layers.']),'Grayscale values');
                       elseif nn == n
                        app.printparamsTable.Data(1:n,{'layerNumbers','layerThicknesses','exposureTimes','grayscaleValues','voidStructures'})...
                            = [printParams(1:n,{'layerNumbers','layerThicknesses','exposureTimes'}),table(gsv),printParams(1:n,{'voidStructures'})];
                       elseif nn > n
                        app.printparamsTable.Data(1:n,{'layerNumbers','layerThicknesses','exposureTimes','grayscaleValues','voidStructures'})...
                            = [printParams(1:n,{'layerNumbers','layerThicknesses','exposureTimes'}),table(gsv(1:n)),printParams(1:n,{'voidStructures'})];
                        msgbox(sprintf(['You input ' num2str(nn) ' numbers.\nThe extra numbers were discarded.']),'Grayscale values');
                       end
                    
                    end
                end
            end
        end

        % Button pushed function: editvoidstructuresButton
        function editvoidstructuresButtonButtonPushed(app, event)
            input1 = inputdlg(sprintf(['Please enter numbers separated by space or comma.\n0 for open structures, and 1 for closed structures.'...
                                       '\nIf only one number is entered, the value will be assigned to all layers.']),'Void structures', [1 120]);
            if ~isempty(input1)
                if ~isempty(input1{1})
                    vstr = (str2num(input1{1}))';
                    nn = length(vstr);
                    
                    printParams = app.printparamsTable.Data;
                    n = size(printParams,1);
                    if nn == 1
                        app.printparamsTable.Data(1:n,{'layerNumbers','layerThicknesses','exposureTimes','grayscaleValues','voidStructures'})...
                            = [printParams(1:n,{'layerNumbers','layerThicknesses','exposureTimes','grayscaleValues'}),table(vstr*ones(n,1))];
                    else
                       if nn < n
                        app.printparamsTable.Data(1:nn,{'layerNumbers','layerThicknesses','exposureTimes','grayscaleValues','voidStructures'})...
                            = [printParams(1:nn,{'layerNumbers','layerThicknesses','exposureTimes','grayscaleValues'}),table(vstr)];
                        msgbox(sprintf(['You input ' num2str(nn) ' numbers.\nThey were assigned to the first ' num2str(nn) ' layers.']),'Void structures');
                       elseif nn == n
                        app.printparamsTable.Data(1:n,{'layerNumbers','layerThicknesses','exposureTimes','grayscaleValues','voidStructures'})...
                            = [printParams(1:n,{'layerNumbers','layerThicknesses','exposureTimes','grayscaleValues'}),table(vstr)];
                       elseif nn > n
                        app.printparamsTable.Data(1:n,{'layerNumbers','layerThicknesses','exposureTimes','grayscaleValues','voidStructures'})...
                            = [printParams(1:n,{'layerNumbers','layerThicknesses','exposureTimes','grayscaleValues'}),table(vstr(1:n))];
                        msgbox(sprintf(['You input ' num2str(nn) ' numbers.\nThe extra numbers were discarded.']),'Void structures');
                       end
                    
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ConverionProfilePrediction = uifigure('Visible', 'off');
            app.ConverionProfilePrediction.Name = 'UI Figure';
            app.printingsetupLabel = uilabel(app.ConverionProfilePrediction);
            app.printingsetupLabel.Text = 'Printing setup';
            app.curekineticsLabel = uilabel(app.ConverionProfilePrediction);
            app.curekineticsLabel.Text = 'Cure kinetics';
            app.Drexel_Image = uiimage(app.ConverionProfilePrediction);
            app.Drexel_Image.ImageSource = 'Drexel.jpg';
            app.curekinetics_Image = uiimage(app.ConverionProfilePrediction);
            app.curekinetics_Image.ImageSource = 'kineticmodel.png';
            app.resinpropertiesLabel = uilabel(app.ConverionProfilePrediction);
            app.resinpropertiesLabel.Text = 'Resin properties';
            app.printparamsTable = uitable(app.ConverionProfilePrediction);
            app.printparamsTable.ColumnName = {'layer number'; 'layer thickness'; 'exposure time'; 'grayscale value'; 'void structure'};
            app.printparamsTable.RowName = {};
            app.printparamsTable.ColumnEditable = [false true true true true];
            app.editlayernumberButton = uibutton(app.ConverionProfilePrediction, 'push');
            app.editlayernumberButton.ButtonPushedFcn = createCallbackFcn(app, @editlayernumberButtonButtonPushed, true);
            app.editlayernumberButton.Text = 'Edit';
            app.editlayerthicknessesButton = uibutton(app.ConverionProfilePrediction, 'push');
            app.editlayerthicknessesButton.ButtonPushedFcn = createCallbackFcn(app, @editlayerthicknessesButtonButtonPushed, true);
            app.editlayerthicknessesButton.Text = 'Edit';
            app.editexposuretimesButton = uibutton(app.ConverionProfilePrediction, 'push');
            app.editexposuretimesButton.ButtonPushedFcn = createCallbackFcn(app, @editexposuretimesButtonButtonPushed, true);
            app.editexposuretimesButton.Text = 'Edit';
            app.editgrayscalevaluesButton = uibutton(app.ConverionProfilePrediction, 'push');
            app.editgrayscalevaluesButton.ButtonPushedFcn = createCallbackFcn(app, @editgrayscalevaluesButtonButtonPushed, true);
            app.editgrayscalevaluesButton.Text = 'Edit';
            app.editvoidstructuresButton = uibutton(app.ConverionProfilePrediction, 'push');
            app.editvoidstructuresButton.ButtonPushedFcn = createCallbackFcn(app, @editvoidstructuresButtonButtonPushed, true);
            app.editvoidstructuresButton.Text = 'Edit';
            app.powerdensityLabel = uilabel(app.ConverionProfilePrediction);
            app.powerdensityLabel.Text = 'power density (mW/cm2)  ';
            app.formulationLabel = uilabel(app.ConverionProfilePrediction);
            app.formulationLabel.Text = 'Formulation';
            app.materialLabel = uilabel(app.ConverionProfilePrediction);
            app.materialLabel.Text = 'material';
            app.materialField = uieditfield(app.ConverionProfilePrediction, 'text');
            app.materialField.Value = 'DA-2 Resin';
            app.photoinitiatorEditFieldLabel = uilabel(app.ConverionProfilePrediction);
            app.photoinitiatorEditFieldLabel.Text = 'photo-initiator';
            app.photoinitiatorField = uieditfield(app.ConverionProfilePrediction, 'text');
            app.photoinitiatorField.Value = 'PPO';
            app.wtEditFieldLabel_5 = uilabel(app.ConverionProfilePrediction);
            app.wtEditFieldLabel_5.Text = 'wt.%';
            app.piconcentrationField = uieditfield(app.ConverionProfilePrediction, 'numeric');
            app.piconcentrationField.Value = 0.7;
            app.photoabsorberEditFieldLabel = uilabel(app.ConverionProfilePrediction);
            app.photoabsorberEditFieldLabel.Text = 'photo-absorber';
            app.photoabsorberField = uieditfield(app.ConverionProfilePrediction, 'text');
            app.photoabsorberField.Value = 'curcumin';
            app.wtEditFieldLabel_6 = uilabel(app.ConverionProfilePrediction);
            app.wtEditFieldLabel_6.Text = 'wt.%';
            app.paconcentrationField = uieditfield(app.ConverionProfilePrediction, 'numeric');
            app.depthofpenetrationDpmumLabel = uilabel(app.ConverionProfilePrediction);
            app.depthofpenetrationDpmumLabel.Text = 'depth of penetration, Dp (\mum)';
            app.depthofpenetrationField = uieditfield(app.ConverionProfilePrediction, 'numeric');
            app.depthofpenetrationField.Value = 550;
            app.criticalenergyEcmJcm2Label = uilabel(app.ConverionProfilePrediction);
            app.criticalenergyEcmJcm2Label.Text = 'critical energy, Ec (mJ/cm^2)';
            app.criticalenergyField = uieditfield(app.ConverionProfilePrediction, 'numeric');
            app.criticalenergyField.Value = 5.6;
            app.criticalconversionalpha_cLabel = uilabel(app.ConverionProfilePrediction);
            app.criticalconversionalpha_cLabel.Text = 'critical conversion, \alpha_c';
            app.criticalconversionField = uieditfield(app.ConverionProfilePrediction, 'numeric');
            app.criticalconversionField.Value = 0.16;
            app.oxygeninhibitionEoimJcm2Label = uilabel(app.ConverionProfilePrediction);
            app.oxygeninhibitionEoimJcm2Label.Text = 'oxygen inhibition, Eoi (mJ/cm^2)';
            app.oxygeninhibitionField = uieditfield(app.ConverionProfilePrediction, 'numeric');
            app.oxygeninhibitionField.Value = 4.6;
            app.ultimateconversionalpha_uLabel = uilabel(app.ConverionProfilePrediction);
            app.ultimateconversionalpha_uLabel.Text = 'ultimate conversion, \alpha_u';
            app.ultimateconversionField = uieditfield(app.ConverionProfilePrediction, 'numeric');
            app.ultimateconversionField.Value = 0.79;
            app.kineticrateconstantkEditFieldLabel = uilabel(app.ConverionProfilePrediction);
            app.kineticrateconstantkEditFieldLabel.Text = 'kinetic rate constant, k';
            app.kineticrateconstantField = uieditfield(app.ConverionProfilePrediction, 'numeric');
            app.kineticrateconstantField.Value = 0.48;
            app.reactionordernLabel = uilabel(app.ConverionProfilePrediction);
            app.reactionordernLabel.Text = 'reaction order, n';
            app.reactionorderField = uieditfield(app.ConverionProfilePrediction, 'numeric');
            app.reactionorderField.Value = 4.4;
            app.doseratefactorwEditFieldLabel = uilabel(app.ConverionProfilePrediction);
            app.doseratefactorwEditFieldLabel.Text = 'dose rate factor, w';
            app.doseratefactorField = uieditfield(app.ConverionProfilePrediction, 'numeric');
            app.doseratefactorField.Value = 0.71;
            app.printerEditFieldLabel = uilabel(app.ConverionProfilePrediction);
            app.printerEditFieldLabel.Text = 'printer';
            app.printerField = uieditfield(app.ConverionProfilePrediction, 'text');
            app.printerField.Value = 'MonoPrinter';
            app.ILabel = uilabel(app.ConverionProfilePrediction);
            app.ILabel.Text = 'I';
            app.equalsignLabel = uilabel(app.ConverionProfilePrediction);
            app.equalsignLabel.Text = ' =';
            app.xgsvLabel = uilabel(app.ConverionProfilePrediction);
            app.xgsvLabel.Text = 'x gsv ';
            app.unitpowerdensityField = uieditfield(app.ConverionProfilePrediction, 'numeric');
            app.unitpowerdensityField.Value = 0.00394;
            app.darkpowerdensityField = uieditfield(app.ConverionProfilePrediction, 'numeric');
            app.darkpowerdensityField.Value = -0.09861;
            app.plussignLabel = uilabel(app.ConverionProfilePrediction);
            app.plussignLabel.Text = '+';
            app.predictButton = uibutton(app.ConverionProfilePrediction, 'push');
            app.predictButton.ButtonPushedFcn = createCallbackFcn(app, @predictButtonPushed, true);
            app.predictButton.Text = 'Predict';
            app.zintervalmumLabel = uilabel(app.ConverionProfilePrediction);
            app.zintervalmumLabel.Text = 'z  interval (\mum)';
            app.zintervalField = uieditfield(app.ConverionProfilePrediction, 'numeric');
            app.zintervalField.Value = 10;
            app.timeintervalsLabel = uilabel(app.ConverionProfilePrediction);
            app.timeintervalsLabel.Text = 'time interval (s)';
            app.tintervalField = uieditfield(app.ConverionProfilePrediction, 'numeric');
            app.tintervalField.Value = 1;
            app.showstepwiseprintingSwitchLabel = uilabel(app.ConverionProfilePrediction);
            app.showstepwiseprintingSwitchLabel.Text = 'show step-wise printing';
            app.showprintingSwitch = uiswitch(app.ConverionProfilePrediction, 'slider');
            app.savetoMatlabworkspaceLabel = uilabel(app.ConverionProfilePrediction);
            app.savetoMatlabworkspaceLabel.Text = 'save to Matlab workspace';
            app.savetoworkspaceSwitch = uiswitch(app.ConverionProfilePrediction, 'slider');
            app.savetoworkspaceSwitch.Items = {'No', 'Yes'};
            app.savetoworkspaceSwitch.Value = 'No';
            app.Image = uiimage(app.ConverionProfilePrediction);
            app.Image.ImageSource = 'Rowan.png';
            % Show the figure after all components are created
            app.ConverionProfilePrediction.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dlpmodel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ConverionProfilePrediction)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConverionProfilePrediction)
        end
    end
end